classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        Tab                         matlab.ui.container.Tab
        Tab2                        matlab.ui.container.Tab
        HistogramEqualizationTab    matlab.ui.container.Tab
        Panel_4                     matlab.ui.container.Panel
        UIAxes_HistEqualizationOut  matlab.ui.control.UIAxes
        Panel_3                     matlab.ui.container.Panel
        UIAxes_HistEqualizationIn   matlab.ui.control.UIAxes
        Panel_2                     matlab.ui.container.Panel
        OutputLabel                 matlab.ui.control.Label
        Image_HistEqualIizationOut  matlab.ui.control.Image
        Panel                       matlab.ui.container.Panel
        BrowseFileButton            matlab.ui.control.Button
        InputLabel                  matlab.ui.control.Label
        Image_HistEqualIizationIn   matlab.ui.control.Image
        HistogramSpecificationTab   matlab.ui.container.Tab
        Panel_10                    matlab.ui.container.Panel
        UIAxes_HistSpecOut          matlab.ui.control.UIAxes
        Panel_9                     matlab.ui.container.Panel
        OutputLabel_3               matlab.ui.control.Label
        Image_HistSpecOut           matlab.ui.control.Image
        Panel_8                     matlab.ui.container.Panel
        UIAxes_HistSpecRef          matlab.ui.control.UIAxes
        Panel_7                     matlab.ui.container.Panel
        UIAxes_HistSpecIn           matlab.ui.control.UIAxes
        Panel_6                     matlab.ui.container.Panel
        BrowseFileButton_3          matlab.ui.control.Button
        ReferenceLabel              matlab.ui.control.Label
        Image_HistSpecRef           matlab.ui.control.Image
        Panel_5                     matlab.ui.container.Panel
        BrowseFileButton_2          matlab.ui.control.Button
        InputLabel_2                matlab.ui.control.Label
        Image_HistSpecIn            matlab.ui.control.Image
    end

    
    properties (Access = private)
        is_no_file_selected = true
        is_no_file_selected_ref = true
        
        img_in
        img_ref
    end
    
    methods (Access = private)
        
        function load_image(app)
            % Get file browsed
            [file, path] = uigetfile('*');
            if file == 0
                % No file selected
                app.is_no_file_selected = true;
            else
                app.is_no_file_selected = false;
                % Return the loaded image
                filepath = fullfile(path, file);
                disp(filepath);
                img = imread(filepath);
                disp(size(img));
                if (is_rgb_same(img) && size(img, 3) == 1)
                    % If grayscale, set to three channel
                    img = cat(3, img, img, img);
                end
                app.img_in = img;

            end
        end

        function load_image_ref(app)
            % Get file browsed
            [file, path] = uigetfile('*');
            if file == 0
                % No file selected
                app.is_no_file_selected_ref = true;
            else
                app.is_no_file_selected_ref = false;
                % Return the loaded image
                filepath = fullfile(path, file);
                disp(filepath);
                img = imread(filepath);
                if (is_rgb_same(img))
                    % If grayscale, set to three channel
                    img = cat(3, img, img, img);
                end
                
                app.img_ref = img;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          screenSize = get(groot,'ScreenSize');
          screenWidth = screenSize(3);
          screenHeight = screenSize(4);
          left = screenWidth*0.1;
          bottom = screenHeight*0.1;
          width = screenWidth*0.8;
          height = screenHeight*0.8;
          drawnow;
          app.UIFigure.Position = [left bottom width height]; 
        end

        % Button pushed function: BrowseFileButton
        function hist_equalize(app, event)
            app.load_image();

            if (~app.is_no_file_selected)
                app.Image_HistEqualIizationIn.ImageSource = app.img_in;

                [raw_hist, equalized_hist, equalized_img] = hist_equalization(app.img_in);
                bar(app.UIAxes_HistEqualizationIn,raw_hist);
                bar(app.UIAxes_HistEqualizationOut,equalized_hist);
                app.Image_HistEqualIizationOut.ImageSource = equalized_img;
            end


        end

        % Button pushed function: BrowseFileButton_2
        function hist_specify(app, event)
            app.load_image();

            if (~app.is_no_file_selected)
                app.Image_HistSpecIn.ImageSource = app.img_in;

                if (~app.is_no_file_selected_ref)
                    [raw_hist_in, raw_hist_ref, specific_hist, specific_img] = hist_specification(app.img_in, app.img_ref);
                    bar(app.UIAxes_HistSpecIn,raw_hist_in);
                    bar(app.UIAxes_HistSpecRef,raw_hist_ref); 
                    bar(app.UIAxes_HistSpecOut,specific_hist);
                    app.Image_HistSpecOut.ImageSource = specific_img;
                end
            end
        end

        % Button pushed function: BrowseFileButton_3
        function hist_specify_ref(app, event)
            app.load_image_ref();

            if (~app.is_no_file_selected_ref)
                app.Image_HistSpecRef.ImageSource = app.img_ref;

                if (~app.is_no_file_selected)
                    [raw_hist_in, raw_hist_ref, specific_hist, specific_img] = hist_specification(app.img_in, app.img_ref);
                    bar(app.UIAxes_HistSpecIn,raw_hist_in);
                    bar(app.UIAxes_HistSpecRef,raw_hist_ref);
                    bar(app.UIAxes_HistSpecOut,specific_hist);
                    app.Image_HistSpecOut.ImageSource = specific_img;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.HistogramEqualizationTab = uitab(app.TabGroup);
            app.HistogramEqualizationTab.Title = 'Histogram Equalization';
            app.Panel = uipanel(app.HistogramEqualizationTab);
            app.Image_HistEqualIizationIn = uiimage(app.Panel);
            app.InputLabel = uilabel(app.Panel);
            app.InputLabel.Text = 'Input';
            app.BrowseFileButton = uibutton(app.Panel, 'push');
            app.BrowseFileButton.ButtonPushedFcn = createCallbackFcn(app, @hist_equalize, true);
            app.BrowseFileButton.Text = 'Browse File';
            app.Panel_2 = uipanel(app.HistogramEqualizationTab);
            app.Image_HistEqualIizationOut = uiimage(app.Panel_2);
            app.OutputLabel = uilabel(app.Panel_2);
            app.OutputLabel.Text = 'Output';
            app.Panel_3 = uipanel(app.HistogramEqualizationTab);
            app.UIAxes_HistEqualizationIn = uiaxes(app.Panel_3);
            title(app.UIAxes_HistEqualizationIn, 'Input Histogram')
            zlabel(app.UIAxes_HistEqualizationIn, 'Z')
            app.Panel_4 = uipanel(app.HistogramEqualizationTab);
            app.UIAxes_HistEqualizationOut = uiaxes(app.Panel_4);
            title(app.UIAxes_HistEqualizationOut, 'Output Histogram')
            zlabel(app.UIAxes_HistEqualizationOut, 'Z')
            app.HistogramSpecificationTab = uitab(app.TabGroup);
            app.HistogramSpecificationTab.Title = 'Histogram Specification';
            app.Panel_5 = uipanel(app.HistogramSpecificationTab);
            app.Image_HistSpecIn = uiimage(app.Panel_5);
            app.InputLabel_2 = uilabel(app.Panel_5);
            app.InputLabel_2.Text = 'Input';
            app.BrowseFileButton_2 = uibutton(app.Panel_5, 'push');
            app.BrowseFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @hist_specify, true);
            app.BrowseFileButton_2.Text = 'Browse File';
            app.Panel_6 = uipanel(app.HistogramSpecificationTab);
            app.Image_HistSpecRef = uiimage(app.Panel_6);
            app.ReferenceLabel = uilabel(app.Panel_6);
            app.ReferenceLabel.Text = 'Reference';
            app.BrowseFileButton_3 = uibutton(app.Panel_6, 'push');
            app.BrowseFileButton_3.ButtonPushedFcn = createCallbackFcn(app, @hist_specify_ref, true);
            app.BrowseFileButton_3.Text = 'Browse File';
            app.Panel_7 = uipanel(app.HistogramSpecificationTab);
            app.UIAxes_HistSpecIn = uiaxes(app.Panel_7);
            title(app.UIAxes_HistSpecIn, 'Input Histogram')
            zlabel(app.UIAxes_HistSpecIn, 'Z')
            app.Panel_8 = uipanel(app.HistogramSpecificationTab);
            app.UIAxes_HistSpecRef = uiaxes(app.Panel_8);
            title(app.UIAxes_HistSpecRef, 'Reference Histogram')
            zlabel(app.UIAxes_HistSpecRef, 'Z')
            app.Panel_9 = uipanel(app.HistogramSpecificationTab);
            app.Image_HistSpecOut = uiimage(app.Panel_9);
            app.OutputLabel_3 = uilabel(app.Panel_9);
            app.OutputLabel_3.Text = 'Output';
            app.Panel_10 = uipanel(app.HistogramSpecificationTab);
            app.UIAxes_HistSpecOut = uiaxes(app.Panel_10);
            title(app.UIAxes_HistSpecOut, 'Output Histogram')
            zlabel(app.UIAxes_HistSpecOut, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end